classdef robot_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        angle1SliderLabel         matlab.ui.control.Label
        angle1Slider              matlab.ui.control.Slider
        angle2SliderLabel         matlab.ui.control.Label
        angle2Slider              matlab.ui.control.Slider
        angle1EditFieldLabel      matlab.ui.control.Label
        angle1EditField           matlab.ui.control.NumericEditField
        angle2EditFieldLabel      matlab.ui.control.Label
        angle2EditField           matlab.ui.control.NumericEditField
        l1EditFieldLabel          matlab.ui.control.Label
        l1EditField               matlab.ui.control.NumericEditField
        l2EditFieldLabel          matlab.ui.control.Label
        l2EditField               matlab.ui.control.NumericEditField
        xEditFieldLabel           matlab.ui.control.Label
        xEditField                matlab.ui.control.NumericEditField
        yEditFieldLabel           matlab.ui.control.Label
        yEditField                matlab.ui.control.NumericEditField
        elbowupCheckBox           matlab.ui.control.CheckBox
        obsticleSliderLabel       matlab.ui.control.Label
        obsticleSlider            matlab.ui.control.Slider
        robotissafeLampLabel      matlab.ui.control.Label
        robotissafeLamp           matlab.ui.control.Lamp
        robotSliderLabel          matlab.ui.control.Label
        robotSlider               matlab.ui.control.Slider
        updatestartButton         matlab.ui.control.Button
        updategoalButton          matlab.ui.control.Button
        calculateobsticlesButton  matlab.ui.control.Button
        findpathButton            matlab.ui.control.Button
        showpathButton            matlab.ui.control.Button
        testButton                matlab.ui.control.Button
        randomButton              matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        outputEditFieldLabel      matlab.ui.control.Label
        outputEditField           matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        objects2 = struct(); % Description
        objects2isempty = 1;
        h; % Description
        h2;
        rOb;
        objects = struct(); % Description
        map = ones(360, 360); % Description
        obsticlesTemp = struct();
        obsticlesTempIsEmpty = true;
        start ;% Description
        goal ;% Description
        finalList % Description
    end
    
    methods (Access = private)
        
        function draw_plot1(app) %draw the simulation
            teta1 = app.angle1EditField.Value;
            teta2 = app.angle2EditField.Value;
            p1 = [app.l1EditField.Value*cosd(teta1) app.l1EditField.Value*sind(teta1)];
            p2 = p1 + [app.l2EditField.Value*cosd(teta1 + teta2) app.l2EditField.Value*sind(teta1 + teta2)];
            app.xEditField.Value = p2(1);
            app.yEditField.Value = p2(2);
            delete(app.h);
            app.h = plot (app.UIAxes, [0; p1(1); p2(1)] ,[0; p1(2); p2(2)] , '-o');
            maxlength = app.l1EditField.Value + app.l2EditField.Value * 1.2;
            app.UIAxes.XLim=[-maxlength,maxlength];
            app.UIAxes.YLim=[-maxlength,maxlength];
            delete(app.h2);
            app.h2 = plot(app.UIAxes2, teta1, teta2, 'o');
            robotinobjects2(app)
        end
        
        function draw_plot2(app) %draw the simulation (used after changing the x and y values)
            x = app.xEditField.Value;
            y = app.yEditField.Value;
            l1 = app.l1EditField.Value;
            l2 = app.l2EditField.Value;
             if (x^2 + y^2 > (l1+l2)^2 ||  2*l1*l2 < abs(x^2 + y^2 - l1^2 - l2^2))
                app.outputEditField.Value = "not posible, please make sure that the robot is long enough";
                return
            end
            if app.elbowupCheckBox.Value == true
                p = hamara2(app, x, y, l1, l2);
            else
                p = hamara1(app, x, y, l1, l2);
            end
            app.angle1EditField.Value = p(1);
            app.angle2EditField.Value = p(2);
            draw_plot1(app)
        end
        
        
        function obsticle(app) % creates the configuration space
            app.objects2 = [];
            app.objects2isempty = 1;
            link1(app)
            elbow_down_teta1 = zeros(0);
            elbow_down_teta2 = zeros(0);
            elbow_up_teta1 = zeros(0);
            elbow_up_teta2 = zeros(0);
            x1 = 0
            x2 = 0
            y1 = 0;
            y2 = 0;
            dis = 0
            angle = 0
            point1 = zeros(2);
            point2 = zeros(2);
            point3 = zeros(2);
            point4 = zeros(2);
            l1 = app.l1EditField.Value;
            divider = app.obsticleSlider.Value - 1;
            xNegativeFlag = false;
            yNegativeFlag = false;
            xNegativeFlag2 = false;
            yNegativeFlag2 = false;
            np = zeros(0);
            pn = zeros(0);
            nn = zeros(0);
            pp = zeros(0);
            hold(app.UIAxes2,'off')
            for z = 1:length(app.objects)
                vertices = app.objects(z).Vertices;
                numOfEdges = size(vertices,1);
                for o = 1:app.robotSlider.Value
                    l2 = (app.l2EditField.Value)*(o/app.robotSlider.Value)
                    for i = 1:numOfEdges
                        x1 = vertices(i, 1);
                        y1 = vertices(i, 2);
                        x2 = vertices(mod(i, numOfEdges)+1, 1);
                        y2 = vertices(mod(i, numOfEdges)+1, 2);
                        dis = sqrt((x1 - x2)^2+(y1 - y2)^2);
                        angle = atan2d((y2 - y1), (x2 - x1));
                        dis = dis/divider;
                        l2 = l2
                        while ((x1^2 + y1^2 > (l1+l2)^2 || abs(2*l1*l2) < abs(x1^2 + y1^2 - l1^2 - l2^2)) && (abs(x1 - x2) >0.1 || abs(y1 - y2) >0.1))
                            x1 = x1+dis*cosd(angle)/divider;
                            y1 = y1+dis*sind(angle)/divider;
                        end
                        if(abs(x1 - x2) <= 0.1 && abs(y1 - y2) <= 0.1)
                            continue
                        end
                        
                        while ((x2^2 + y2^2 > (l1+l2)^2 || abs(2*l1*l2) < abs(x2^2 + y2^2 - l1^2 - l2^2)) && (abs(x1 - x2) >0.1 || abs(y1 - y2) >0.1))
                            x2 = x2-dis*cosd(angle)/divider;
                            y2 = y2-dis*sind(angle)/divider;
                        end
                        
                        point1 = hamara1(app, x1, y1, l1, l2);
                        point2 = hamara2(app, x1, y1, l1, l2);


                        elbow_down_teta1(end+1) = point1(1);
                        elbow_down_teta2(end+1) = point1(2);
                        elbow_up_teta1(end+1) = point2(1);
                        elbow_up_teta2(end+1) = point2(2);
                        dis = sqrt((x1 - x2)^2+(y1 - y2)^2);
                        dis = dis/divider;
                        originalX2 = x2;
                        originalY2 = y2;
                        
                        for j = 1:divider+1
                            if(abs(x1 - originalX2) <= 0.1 && abs(y1 - originalY2) <= 0.1)
                                continue
                            end
                            x2 = x1+dis*cosd(angle)
                            y2 = y1+dis*sind(angle)
                            if CheckHamara(app, x2, y2, l1, l2) == 1
                                point1 = hamara1(app, x2, y2, l1, l2);
                                point2 = hamara2(app, x2, y2, l1, l2);
                                elbow_down_teta1(end+1) = point1(1);
                                elbow_down_teta2(end+1) = point1(2);
                                elbow_up_teta1(end+1) = point2(1);
                                elbow_up_teta2(end+1) = point2(2);
                            end
                            x1 = x2;
                            y1 = y2;
                        end
                    end
                    
                end
                for i = 1:length(elbow_up_teta1)
                    if(elbow_up_teta1(i) >= 180  && elbow_up_teta2(i) >= 180)
                        pp(end+1, 1) = elbow_up_teta1(i);
                        pp(end, 2) = elbow_up_teta2(i);
                    elseif(elbow_up_teta1(i) <= 180  && elbow_up_teta2(i) >= 180)
                        np(end+1, 1) = elbow_up_teta1(i);
                        np(end, 2) = elbow_up_teta2(i);
                    elseif(elbow_up_teta1(i) >= 180  && elbow_up_teta2(i) <= 180)
                        pn(end+1, 1) = elbow_up_teta1(i);
                        pn(end, 2) = elbow_up_teta2(i);
                    elseif(elbow_up_teta1(i) <= 180  && elbow_up_teta2(i) <= 180)
                        nn(end+1, 1) = elbow_up_teta1(i);
                        nn(end, 2) = elbow_up_teta2(i);
                    end
                end
                sortInToObjects2(app, pp)
                sortInToObjects2(app, np)
                sortInToObjects2(app, pn)
                sortInToObjects2(app, nn)
                np = zeros(0);
                pn = zeros(0);
                nn = zeros(0);
                pp = zeros(0);
                for i = 1:length(elbow_down_teta1)
                    if(elbow_down_teta1(i) >= 180  && elbow_down_teta2(i) >= 180)
                        pp(end+1, 1) = elbow_down_teta1(i)
                        pp(end, 2) = elbow_down_teta2(i)

                    elseif(elbow_down_teta1(i) <= 180  && elbow_down_teta2(i) >= 180)
                        np(end+1, 1) = elbow_down_teta1(i)
                        np(end, 2) = elbow_down_teta2(i);
                    elseif(elbow_down_teta1(i) >= 180  && elbow_down_teta2(i) <= 180)
                        pn(end+1, 1) = elbow_down_teta1(i)
                        pn(end, 2) = elbow_down_teta2(i)
                    elseif(elbow_down_teta1(i) <= 180  && elbow_down_teta2(i) <= 180)
                        nn(end+1, 1) = elbow_down_teta1(i)
                        nn(end, 2) = elbow_down_teta2(i)
                    end
                end
                sortInToObjects2(app, pp);
                sortInToObjects2(app, np);
                sortInToObjects2(app, pn);
                sortInToObjects2(app, nn);
                np = zeros(0);
                pn = zeros(0);
                nn = zeros(0);
                pp = zeros(0);
                elbow_down_teta1 = zeros(0);
                elbow_down_teta2 = zeros(0);
                elbow_up_teta1 = zeros(0);
                elbow_up_teta2 = zeros(0);
                over = overlaps(app.obsticlesTemp);
                for a = 1:length(app.obsticlesTemp)
                    for b = 1:length(app.obsticlesTemp)
                        if (a == b)
                            continue
                        end
                        if(over(a, b) == 1)
                            u = union(app.obsticlesTemp(a), app.obsticlesTemp(b));
                            k = convhull(u.Vertices(:, 1), u.Vertices(:, 2));
                            app.obsticlesTemp(a) = polyshape(u.Vertices(k, 1), u.Vertices(k, 2));
                            app.obsticlesTemp(b) = polyshape(u.Vertices(k, 1), u.Vertices(k, 2));
                        end
                    end
                end
                if(app.objects2isempty == 1)
                    app.objects2 = polybuffer(union(app.obsticlesTemp), 0);
                    app.objects2isempty = 0;
                else
                    app.objects2(end+1) = polybuffer(union(app.obsticlesTemp), 0);
                end
                app.obsticlesTemp = struct();
                app.obsticlesTempIsEmpty = true;
            end
            if(app.objects2isempty == 0)
                app.objects2 = union(app.objects2);
                plot(app.UIAxes2, app.objects2)
                
                app.map = ~(mpoly2mask(app.objects2.Vertices, 1:360, 1:360))
            end
            
            %             if(app.objects2isempty == 0)
            %                 for i = 1:length(app.objects2)
            %                     plot(app.UIAxes2, app.objects2(i))
            %                 end
            %             end
            hold(app.UIAxes2,'on')
        end
        function [p]=hamara1(app, x, y, l1, l2)%finds what should be the angles between the robot's links to get him to the given (x, y) position in the elbow down cofiguration
            teta2 = acosd((x^2 + y^2 - l1^2 - l2^2)/(2*l1*l2))
            teta1 = rad2deg(atan2(y,x) - atan2((l2*sind(teta2)) , (l1 + l2*cosd(teta2))));
            p = [mod(teta1, 360), mod(teta2, 360)];
            if(p(1) < 1)
                p(1) = 360
            end
            if(p(2) < 1)
                p(2) = 360
            end
            
        end
        function [p]=hamara2(app, x, y, l1, l2)%finds what should be the angles between the robot's links to get him to the given (x, y) position in the elbow up cofiguration
            teta2 = acosd((x^2 + y^2 - l1^2 - l2^2)/(2*l1*l2)) *-1
            teta1 = atan2d(y,x) - atan2d((l2*sind(teta2)) , (l1 + l2*cosd(teta2)))
            p = [mod(teta1, 360), mod(teta2, 360)];
            if(p(1) < 1)
                p(1) = 360
            end
            if(p(2) < 1)
                p(2) = 360
            end
        end
        
        function link1(app)%find collision of link 1 with the obstecales and creates matching obstecales in the configuration space
            l = app.l1EditField.Value;
            arr = zeros(0);
            flag = false;
            for i = 0:361
                p_original = [l*cosd(i), l*sind(i)];
                if (flag == true)
                    currentl1IsTouching = false;
                    for o = 1:app.robotSlider.Value
                        p = p_original*o/app.robotSlider.Value;
                        if(pointinobjects1(app, p))
                            currentl1IsTouching = true;
                        end
                    end
                    if (~currentl1IsTouching || i == 361)
                        arr(end, 2) = i+2;
                        flag = false;
                    end
                else
                    currentl1IsTouching = false;
                    for o = 1:app.robotSlider.Value
                        p = p_original*o/app.robotSlider.Value;
                        if(pointinobjects1(app, p) == true)
                            currentl1IsTouching = true;
                        end
                    end
                    if (currentl1IsTouching)
                        arr(end + 1, 1) = i - 2;
                        flag = true;
                    end
                end
            end
            for i = 1:size(arr, 1)
                if(app.objects2isempty == 1)
                    app.objects2 = polyshape([arr(i, 1), arr(i, 1), arr(i, 2), arr(i, 2)], [0, 360, 360, 0]);
                    app.objects2isempty = 0;
                else
                    app.objects2(end+1) = polyshape([arr(i, 1), arr(i, 1), arr(i, 2), arr(i, 2)], [0, 360, 360, 0]);
                end
            end
        end
        
        function results = pointinobjects1(app, p)%check if robot is colliding with the obstecles using the work space
            for i = 1:length(app.objects)
                if (inpolygon(p(1), p(2), app.objects(i).Vertices(1:end, 1), app.objects(i).Vertices(1:end, 2)) == 1)
                    results = true;
                    return
                end
            end
            results = false;
        end
        
        function robotinobjects2(app) %indicates the result of pointinobjects2
                        if (app.objects2isempty == 0)
                            if pointinobjects2(app, [app.angle1EditField.Value app.angle2EditField.Value])
                                app.robotissafeLamp.Color = [1 0 0]
                            else
                                app.robotissafeLamp.Color = [0 1 0]
                            end                        
                        end
     
        end
        function results = pointinobjects2(app, p) %check if robot is colliding with the obstecles using the configuration space
            results = false;
            if (app.objects2isempty == 0)
                p = floor(p)
                if (app.map(p(2), p(1)) == 0)
                    results = true;
                end
            end  
        end
           
        function sortInToObjects2(app, a)%unused???

            if (length(a)>3)
                k = convhull(a(:, 1), a(:, 2));
                if(app.obsticlesTempIsEmpty)
                    app.obsticlesTemp = polyshape(a(k, 1), a(k, 2));
                    app.obsticlesTempIsEmpty = false;
                else
                    app.obsticlesTemp(end+1) = polyshape(a(k, 1), a(k, 2));
                end
                app.obsticlesTemp(end) = polybuffer(app.obsticlesTemp(end), 3);
            end
        end


        function path(app)%find path from start node to goal node using any of the algorithms

%             app.finalList = A1(app.map, app.start, app.goal); 
%             app.finalList = A2(app.map, app.start, app.goal); 
%             app.finalList = bfs(app.map, app.start, app.goal); 
%             app.finalList = dfs(app.map, app.start, app.goal); 
            app.finalList = Y(app.map, app.start, app.goal); 
        end
        
        function show_path(app) %show the path found using the algorithms
            for i = 1:size(app.finalList, 1)
                app.angle1EditField.Value = app.finalList(i, 1);
                app.angle2EditField.Value = app.finalList(i, 2);
                draw_plot1(app)
                pause(0.1)
            end
        end
        
        function randomStartAndGoal(app) %makes random start node and random goal node
            num = floor(rand(1)*(2))
            x = floor(rand(1)*5 -5)
            y = floor(rand(1)*5 +5)
            if num>0
                y = y - 20
            end
            app.objects(3) = polyshape([x, x, x+5, x+5], [y, y+5, y+5, y]);
            delete(app.rOb)
            app.rOb = plot(app.UIAxes, app.objects(3))
            obsticle(app)
            
            flag = 0
            num = floor(rand(1)*(2))  
            while(flag == 0 || pointinobjects2(app, app.start))
                x = floor(rand(1)*20 -10)
                y = floor(rand(1)*30 - 15)
                flag = CheckHamara(app, x, y, app.l1EditField.Value, app.l2EditField.Value)
                if flag
                    if(num>0)
                        app.start = hamara1(app, x, y, app.l1EditField.Value, app.l2EditField.Value)
                    else
                        app.start = hamara2(app, x, y, app.l1EditField.Value, app.l2EditField.Value)
                    end
                end
            end
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2, app.start(1), app.start(2), 'o')
            
            flag = 0
            num = floor(rand(1)*(2))  

            while(flag == 0 || pointinobjects2(app, app.goal))
                x = floor(rand(1)*20 -10)
                y = floor(rand(1)*30 - 15)
                flag = CheckHamara(app, x, y, app.l1EditField.Value, app.l2EditField.Value)
                if flag
                    if(num>0)
                        app.goal = hamara1(app, x, y, app.l1EditField.Value, app.l2EditField.Value)
                    else
                        app.goal = hamara2(app, x, y, app.l1EditField.Value, app.l2EditField.Value)
                    end
                end   
            end
            if(num>0)
                app.goal(2, :) = hamara2(app, x, y, app.l1EditField.Value, app.l2EditField.Value)
            else
                app.goal(2, :) = hamara1(app, x, y, app.l1EditField.Value, app.l2EditField.Value)
            end
            if pointinobjects2(app, app.goal(2, :)) == 1
                app.goal(2, :) = [];
            else
                plot(app.UIAxes2, app.goal(2, 1), app.goal(2, 2), 'o')
            end
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2, app.goal(1, 1), app.goal(1, 2), 'o')
            
        end
        
        function test(app) %runs the path algorithm multiple times to compare the algorithms later
            visitedCount_bfs = []
            finalCount_bfs =[]
            visitedCount_A2 =[]
            finalCount_A2 =[]
            visitedCount_A1 =[]
            finalCount_A1 =[]
            visitedCount_dfs =[]
            finalCount_dfs = []
            visitedCount_Y = []
            finalCount_Y = []
            save('test_bfs.mat', 'visitedCount_bfs', 'finalCount_bfs')
            save('test_A1.mat', 'visitedCount_A1', 'finalCount_A1')
            save('test_A2.mat', 'visitedCount_A2', 'finalCount_A2')
            save('test_dfs.mat', 'visitedCount_dfs', 'finalCount_dfs')
            save('test_Y.mat', 'visitedCount_Y', 'finalCount_Y')
            for i = 1:100
                randomStartAndGoal(app)
                path(app)
            end
        end
        
        function results = CheckHamara(app, x, y, l1, l2)%Checks if hamra is possibole so it can be used without crashing the app
            results = 1;
            if (x^2 + y^2 > (l1+l2)^2 ||  2*l1*l2 < abs(x^2 + y^2 - l1^2 - l2^2))
                results = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes)
            app.UIAxes2.XLim=[1, 360];
            app.UIAxes2.YLim=[1, 360];
            app.objects = polyshape([12, 12, 11, 11], [20, -20, -20, 20]);
            app.objects(2) = polyshape([-12, -12, -11, -11], [20, -20, -20, 20]);
            app.h = plot(app.UIAxes, [0;app.l1EditField.Value;app.l1EditField.Value+app.l2EditField.Value], [0 ; 0 ; 0]);
            app.h2 = plot(app.UIAxes2, 0, 0, 'o');
            hold(app.UIAxes, 'on')
            for i = 1:length(app.objects)
                plot(app.UIAxes, app.objects(i));
            end

            draw_plot1(app)
        end

        % Value changed function: angle1Slider
        function angle1SliderValueChanged(app, event)
            value = app.angle1Slider.Value;
            app.angle1EditField.Value = value;
            draw_plot1(app)
        end

        % Value changed function: angle1EditField
        function angle1EditFieldValueChanged(app, event)
            value = app.angle1EditField.Value;
            app.angle1Slider.Value = value;
            draw_plot1(app)
        end

        % Value changed function: angle2Slider
        function angle2SliderValueChanged(app, event)
            value = app.angle2Slider.Value;
            app.angle2EditField.Value = value;
            draw_plot1(app)
        end

        % Value changed function: angle2EditField
        function angle2EditFieldValueChanged(app, event)
            value = app.angle2EditField.Value;
            app.angle2Slider.Value = value;
            draw_plot1(app)
        end

        % Value changed function: l1EditField
        function l1EditFieldValueChanged(app, event)
            value = app.l1EditField.Value;
            draw_plot1(app)
        end

        % Value changed function: l2EditField
        function l2EditFieldValueChanged(app, event)
            value = app.l2EditField.Value;
            draw_plot1(app)
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            draw_plot2(app)
        end

        % Value changed function: yEditField
        function yEditFieldValueChanged(app, event)
            value = app.yEditField.Value;
            draw_plot2(app)
        end

        % Value changed function: elbowupCheckBox
        function elbowupCheckBoxValueChanged(app, event)
            value = app.elbowupCheckBox.Value;
            draw_plot2(app)
        end

        % Callback function
        function obsticleButtonPushed(app, event)
            uiresume
        end

        % Value changing function: angle1Slider
        function angle1SliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            value = event.Key;
            if strcmp(value, 'leftarrow')==1
                if(app.angle1EditField.Value - 10 < 1)
                    app.angle1EditField.Value = app.angle1EditField.Value + 350;
                else
                    app.angle1EditField.Value = app.angle1EditField.Value - 10;
                end
            end
            if strcmp(value, 'rightarrow')==1
                if(app.angle1EditField.Value + 10 > 360)
                    app.angle1EditField.Value = app.angle1EditField.Value - 350;
                else
                    app.angle1EditField.Value = app.angle1EditField.Value + 10;
                end
            end
            if strcmp(value, 'uparrow')==1
                if(app.angle2EditField.Value + 10 > 360)
                    app.angle2EditField.Value = app.angle2EditField.Value - 350;
                else
                    app.angle2EditField.Value = app.angle2EditField.Value + 10;
                end
            end
            if strcmp(value, 'downarrow')==1
                if(app.angle2EditField.Value - 10 < 1)
                    app.angle2EditField.Value = app.angle2EditField.Value + 350;
                else
                    app.angle2EditField.Value = app.angle2EditField.Value - 10;
                end
            end
            app.outputEditField.Value = value;
            app.angle1Slider.Value = app.angle1EditField.Value;
            app.angle2Slider.Value = app.angle2EditField.Value;
            draw_plot1(app)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            p = app.UIAxes.CurrentPoint(1,:);
            if (p(1) >= app.UIAxes.XLim(1) & p(1) <= app.UIAxes.XLim(2) & p(2) >= app.UIAxes.YLim(1) & p(2) <= app.UIAxes.YLim(2))
                app.xEditField.Value = p(1);
                app.yEditField.Value = p(2);
                draw_plot2(app)
            end
            
            p = app.UIAxes2.CurrentPoint(1,:);
            if (p(1) >= app.UIAxes2.XLim(1) & p(1) <= app.UIAxes2.XLim(2) & p(2) >= app.UIAxes2.YLim(1) & p(2) <= app.UIAxes2.YLim(2))
                app.angle1EditField.Value = p(1);
                app.angle2EditField.Value = p(2);
                draw_plot1(app)
            end
            
        end

        % Button pushed function: calculateobsticlesButton
        function calculateobsticlesButtonPushed(app, event)
            obsticle(app)
        end

        % Button pushed function: findpathButton
        function findpathButtonPushed(app, event)
            path(app)
        end

        % Button pushed function: updatestartButton
        function updatestartButtonPushed(app, event)
            app.start(2) = floor(app.angle1EditField.Value);
            app.start(1) = floor(app.angle2EditField.Value);
        end

        % Button pushed function: updategoalButton
        function updategoalButtonPushed(app, event)
            app.goal(2) = floor(app.angle1EditField.Value);
            app.goal(1) = floor(app.angle2EditField.Value);
        end

        % Button pushed function: showpathButton
        function showpathButtonPushed(app, event)
            show_path(app)
        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
            test(app)
        end

        % Button pushed function: randomButton
        function randomButtonPushed(app, event)
            randomStartAndGoal(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {758, 758};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {224, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.angle1SliderLabel = uilabel(app.LeftPanel);
            app.angle1SliderLabel.Text = 'angle 1';
            app.angle1Slider = uislider(app.LeftPanel);
            app.angle1Slider.Limits = [1 360];
            app.angle1Slider.ValueChangedFcn = createCallbackFcn(app, @angle1SliderValueChanged, true);
            app.angle1Slider.ValueChangingFcn = createCallbackFcn(app, @angle1SliderValueChanging, true);
            app.angle1Slider.Value = 360;
            app.angle2SliderLabel = uilabel(app.LeftPanel);
            app.angle2SliderLabel.Text = 'angle 2';
            app.angle2Slider = uislider(app.LeftPanel);
            app.angle2Slider.Limits = [1 360];
            app.angle2Slider.ValueChangedFcn = createCallbackFcn(app, @angle2SliderValueChanged, true);
            app.angle2Slider.Value = 360;
            app.angle1EditFieldLabel = uilabel(app.LeftPanel);
            app.angle1EditFieldLabel.Text = 'angle 1';
            app.angle1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.angle1EditField.Limits = [1 360];
            app.angle1EditField.ValueChangedFcn = createCallbackFcn(app, @angle1EditFieldValueChanged, true);
            app.angle1EditField.Value = 360;
            app.angle2EditFieldLabel = uilabel(app.LeftPanel);
            app.angle2EditFieldLabel.Text = 'angle 2';
            app.angle2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.angle2EditField.Limits = [1 360];
            app.angle2EditField.ValueChangedFcn = createCallbackFcn(app, @angle2EditFieldValueChanged, true);
            app.angle2EditField.Value = 360;
            app.l1EditFieldLabel = uilabel(app.LeftPanel);
            app.l1EditFieldLabel.Text = 'l1';
            app.l1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.l1EditField.ValueChangedFcn = createCallbackFcn(app, @l1EditFieldValueChanged, true);
            app.l1EditField.Value = 10;
            app.l2EditFieldLabel = uilabel(app.LeftPanel);
            app.l2EditFieldLabel.Text = 'l2';
            app.l2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.l2EditField.ValueChangedFcn = createCallbackFcn(app, @l2EditFieldValueChanged, true);
            app.l2EditField.Value = 8;
            app.xEditFieldLabel = uilabel(app.LeftPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.LeftPanel, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.yEditFieldLabel = uilabel(app.LeftPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.LeftPanel, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @yEditFieldValueChanged, true);
            app.elbowupCheckBox = uicheckbox(app.LeftPanel);
            app.elbowupCheckBox.ValueChangedFcn = createCallbackFcn(app, @elbowupCheckBoxValueChanged, true);
            app.elbowupCheckBox.Text = 'elbow up';
            app.elbowupCheckBox.Value = true;
            app.obsticleSliderLabel = uilabel(app.LeftPanel);
            app.obsticleSliderLabel.Text = 'obsticle';
            app.obsticleSlider = uislider(app.LeftPanel);
            app.obsticleSlider.Limits = [2 100];
            app.obsticleSlider.Value = 20;
            app.robotissafeLampLabel = uilabel(app.LeftPanel);
            app.robotissafeLampLabel.Text = 'robot is safe';
            app.robotissafeLamp = uilamp(app.LeftPanel);
            app.robotSliderLabel = uilabel(app.LeftPanel);
            app.robotSliderLabel.Text = 'robot';
            app.robotSlider = uislider(app.LeftPanel);
            app.robotSlider.Limits = [1 100];
            app.robotSlider.Value = 10;
            app.updatestartButton = uibutton(app.LeftPanel, 'push');
            app.updatestartButton.ButtonPushedFcn = createCallbackFcn(app, @updatestartButtonPushed, true);
            app.updatestartButton.Text = {'update start'; ''};
            app.updategoalButton = uibutton(app.LeftPanel, 'push');
            app.updategoalButton.ButtonPushedFcn = createCallbackFcn(app, @updategoalButtonPushed, true);
            app.updategoalButton.Text = 'update goal';
            app.calculateobsticlesButton = uibutton(app.LeftPanel, 'push');
            app.calculateobsticlesButton.ButtonPushedFcn = createCallbackFcn(app, @calculateobsticlesButtonPushed, true);
            app.calculateobsticlesButton.Text = {'calculate obsticles'; ''};
            app.findpathButton = uibutton(app.LeftPanel, 'push');
            app.findpathButton.ButtonPushedFcn = createCallbackFcn(app, @findpathButtonPushed, true);
            app.findpathButton.Text = {'find path'; ''};
            app.showpathButton = uibutton(app.LeftPanel, 'push');
            app.showpathButton.ButtonPushedFcn = createCallbackFcn(app, @showpathButtonPushed, true);
            app.showpathButton.Text = 'show path';
            app.testButton = uibutton(app.LeftPanel, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Text = 'test';
            app.randomButton = uibutton(app.LeftPanel, 'push');
            app.randomButton.ButtonPushedFcn = createCallbackFcn(app, @randomButtonPushed, true);
            app.randomButton.Text = 'random';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'work space')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, {'configuration space'; ''})
            xlabel(app.UIAxes2, 'θ1')
            ylabel(app.UIAxes2, 'θ2')
            app.outputEditFieldLabel = uilabel(app.RightPanel);
            app.outputEditFieldLabel.Text = 'output';
            app.outputEditField = uieditfield(app.RightPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end